classdef p3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FreqLabel                    matlab.ui.control.Label
        DownsamplingFactorEditField  matlab.ui.control.NumericEditField
        DownsamplingFactorEditFieldLabel  matlab.ui.control.Label
        UploadButton                 matlab.ui.control.Button
        Results                      matlab.ui.control.Label
        RecordButton                 matlab.ui.control.Button
        SamplingFrequencyEditField   matlab.ui.control.NumericEditField
        SamplingFrequencyLabel       matlab.ui.control.Label
        UIAxes_Freq                  matlab.ui.control.UIAxes
        UIAxes_Time                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        tr = 2; % Time recorded in seconds.
        
        %% Constant thresholds in Hz.
        elder_male = 135; % Elder male threshold.
        middle_male = 280; % Middle aged male threshold.
        teen_male = 410; % Teen male threshold.
        elder_female = 450; % Elder female threshold.
        kid_male = 480; % Kid male threshold.
        middle_female = 505; % Middle aged female threshold.
        teen_female = 530; % Elder male threshold.
    end
    
    methods (Access = private)
        
        function [Mdb,freq] = espectro(app,x,fm)
            %  x    --- señal de entrada
            %  fm   --- frecuencia de muestreo 
            %  Mdb    --- magnitud de la transformada de Fourier
            %  freq --- eje de frecuencia en Hz 
 
            Np=8192;                             % Número de puntos de la DFT
            L=length(x);                         % Longitud de la señal
            Tm=(1/fm);                           % Tiempo de muestreo
            Mp=ceil(Np/2);                       % Mitad de puntos de la FFT
            wd=0:2*pi/Np:2*pi*(Np-1)/Np;         % Vector de Frec. discreta
            wdo=zeros(1,Np);                     % Vector de Frec. disc. organizadas
            wc=zeros(1,Np);                      % Vector de Frec. cont. en rad/seg
            fc=zeros(1,Np);                      % Vector de Frec. cont. en Hz
            z=abs(fft(x,Np));          % Magnitud de FFT de la señal en db
            zo=zeros(1,Np);                      % Vector para reorganizar la FFT
            t=0:Tm:Tm*(L-1);                     % Vector de tiempo
            
            % Reorganización de frecuencias
            wdo(Np-Mp+1:end)=wd(1:Mp);
            wdo(1:Np-Mp)=wd(Mp+1:end)-2*pi;
            
            % Frecuencia continua en Hz
            wc=wdo/Tm;
            fc=wc/(2*pi);
            
            % Reorganización de la FFT
            zo(Np-Mp+1:end)=z(1:Mp);
            zo(1:Np-Mp)=z(Mp+1:end);
            
            M=zo;
            Mdb=pow2db(abs(M));
            
            freq=fc;
            plot(app.UIAxes_Freq,freq,Mdb);
            
        end
        
        function f0 = getF0(app, Mdb, freq)
            l = length(Mdb);
            [~,iMax] = max(Mdb(ceil(l/2)+1:end));
            f0 = freq(ceil(l/2) + iMax);
        end
        
        function [gender, age] = getGenderAndAge(app, f)
            if f < app.elder_male
                gender = "male";
                age = "elder";
            elseif f < app.middle_male
                gender = "male";
                age = "middle aged";
            elseif f < app.teen_male
                gender = "male";
                age = "teenager";
            elseif f < app.elder_female
                gender = "female";
                age = "elder";
            elseif f < app.kid_male
                gender = "male";
                age = "kid";
            elseif f < app.middle_female
                gender = "female";
                age = "middle aged";
            elseif f < app.teen_female
                gender = "female";
                age = "teenager";
            else
                gender = "female";
                age = "kid";
            end
        end
        
        function setResults(app, f)
            [gender, age] = getGenderAndAge(app, f);
            app.Results.Text = "The fundamental frequency is " + f + " Hz." + ...
                " Gender = " + gender + " Age = " + age;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            fs = app.SamplingFrequencyEditField.Value;
            
            if fs > 0
                app.RecordButton.Enable = false;
                app.FreqLabel.Text = "";
                
                recorder = audiorecorder(fs,16,1);
                recordblocking(recorder,app.tr);
                audioSignal = recorder.getaudiodata();
                
                t = 1/fs:1/fs:app.tr;
                plot(app.UIAxes_Time,t,audioSignal);
                sound(audioSignal,fs);
                
                [Mdb,freq] = espectro(app,audioSignal, fs);
                f0 = getF0(app,Mdb,freq);
                
                setResults(app, f0);
                app.RecordButton.Enable = true;
            end
            
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            % Downsampling factor
            n = app.DownsamplingFactorEditField.Value;
            [file,path] = uigetfile('*.wav');
            
            if ~isequal(file,0) && n > 0
                [audioSignal, fs] = audioread(strcat(path,file));
                
                % Clip the audio depending on tr (time recorded).
                audioSignal = audioSignal(1:app.tr*fs);
                audioSignal = downsample(audioSignal, n);
                
                fs_ds = fs/n;
                
                t = 1/fs_ds:1/fs_ds:app.tr;
                
                sound(audioSignal,fs_ds);
                plot(app.UIAxes_Time,t,audioSignal);
                app.FreqLabel.Text = "SamplingFreq = " + fs + ...
                " DownsampledFreq = " + fs_ds;
                
                [Mdb,freq] = espectro(app,audioSignal, fs_ds);
                f0 = getF0(app,Mdb,freq);
                setResults(app, f0);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_Time = uiaxes(app.UIFigure);
            title(app.UIAxes_Time, 'Audio Signal')
            xlabel(app.UIAxes_Time, 'Time (s)')
            ylabel(app.UIAxes_Time, 'Energy (J)')
            zlabel(app.UIAxes_Time, 'Z')
            app.UIAxes_Freq = uiaxes(app.UIFigure);
            title(app.UIAxes_Freq, 'Fourier Transform')
            xlabel(app.UIAxes_Freq, 'Frequency (Hz)')
            ylabel(app.UIAxes_Freq, 'Intensity (dB)')
            zlabel(app.UIAxes_Freq, 'Z')
            app.SamplingFrequencyLabel = uilabel(app.UIFigure);
            app.SamplingFrequencyLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingFrequencyEditField.Value = 8000;
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.Results = uilabel(app.UIFigure);
            app.Results.Text = '';
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.DownsamplingFactorEditFieldLabel = uilabel(app.UIFigure);
            app.DownsamplingFactorEditFieldLabel.Text = 'Downsampling Factor';
            app.DownsamplingFactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.DownsamplingFactorEditField.Value = 1;
            app.FreqLabel = uilabel(app.UIFigure);
            app.FreqLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = p3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end